classdef NewGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        text16                  matlab.ui.control.Label
        connectToArduino        matlab.ui.control.Button
        numIterations           matlab.ui.control.EditField
        text32                  matlab.ui.control.Label
        text33                  matlab.ui.control.Label
        text34                  matlab.ui.control.Label
        leftCalibDuration       matlab.ui.control.EditField
        rightCalibDuration      matlab.ui.control.EditField
        getRightCalibDuration   matlab.ui.control.Button
        centerPokeTrigger       matlab.ui.control.EditField
        centerPokeRewardWindow  matlab.ui.control.EditField
        leftRewardProb          matlab.ui.control.EditField
        rightRewardProb         matlab.ui.control.EditField
        rewardDurationRight     matlab.ui.control.EditField
        rewardDurationLeft      matlab.ui.control.EditField
        minInterTrialInterval   matlab.ui.control.EditField
        blockRangeMin           matlab.ui.control.EditField
        blockRangeMax           matlab.ui.control.EditField
        text12                  matlab.ui.control.Label
        statsTable              matlab.ui.control.Table
        text23                  matlab.ui.control.Label
        text24                  matlab.ui.control.Label
        text25                  matlab.ui.control.Label
        text26                  matlab.ui.control.Label
        text27                  matlab.ui.control.Label
        text28                  matlab.ui.control.Label
        text29                  matlab.ui.control.Label
        text30                  matlab.ui.control.Label
        text31                  matlab.ui.control.Label
        mouseName               matlab.ui.control.EditField
        chooseFolder            matlab.ui.control.Button
        folderPath              matlab.ui.control.Label
        runExperiment           matlab.ui.control.Button
        stopExperiment          matlab.ui.control.Button
        save                    matlab.ui.control.CheckBox
        text14                  matlab.ui.control.Label
        text15                  matlab.ui.control.Label
        text21                  matlab.ui.control.Label
        reset                   matlab.ui.control.Button
        getLeftCalibDuration    matlab.ui.control.Button
        laserstimprob           matlab.ui.control.EditField
        markov                  matlab.ui.control.EditField
        text38                  matlab.ui.control.Label
        useMarkov               matlab.ui.control.CheckBox
        text40                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function edit17_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to laserstimprob (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function ledDuringRewardWindow_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to ledDuringRewardWindow (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('NewGUI_App.mat');
            
            % Set component properties that require runtime configuration
            app.statsTable.ColumnFormat = {[] [] []};
            app.statsTable.Data = componentData.statsTable.Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function NewGUI_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to NewGUI (see VARARGIN)
            
            % Choose default command line output for NewGUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            global handlesCopy
            handlesCopy = handles;
            % UIWAIT makes NewGUI wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            %initially disables the Stop Experiment Button and save checkmark.
            %also initially sets the checkmark to checked
            set(handles.stopExperiment,'enable','off');
            set(handles.save,'enable','off');
            set(handles.save,'Value',1)
            global calib
            calib.left = str2double(get(handles.leftCalibDuration,'String'));
            calib.right = str2double(get(handles.rightCalibDuration,'String'));
            set(handles.statsTable,'enable','off');
            set(handles.leftCalibDuration,'enable','off');
            set(handles.rightCalibDuration,'enable','off');
            set(handles.getLeftCalibDuration,'enable','off');
            set(handles.getRightCalibDuration,'enable','off');
            set(handles.numIterations,'enable','off');
        end

        % Button pushed function: chooseFolder
        function chooseFolder_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to chooseFolder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            folder = uigetdir;
            set(handles.folderPath, 'String', folder);
        end

        % Button pushed function: connectToArduino
        function connectToArduino_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %establishes a connection with the arduino to calibrate the number of water
            %dispensed by the solenoids by setting up a temporary calibration_log_ file
            %that is then deleted at the end of the function
            global arduinoConnection arduinoPort
            global arduinoMessageString
            arduinoMessageString = '';
            clearPorts();
            arduinoPortNum = findArduinoPort();
            if ~arduinoPortNum
                disp('Can''t find serial port with Arduino')
                return
            end
            global info calib
            info = struct;
            info.folderName = pwd;
            setupLogging('calibration_log');
            arduinoConnection = 0;
            arduinoPort = setupArduinoSerialPort(arduinoPortNum);
            % wait for Arduino startup
            fprintf('Waiting for Arduino startup')
            while (arduinoConnection == 0)
                fprintf('.');
                pause(0.5);
            end
            fprintf('\n')
            
            global leftPort
            leftPort = NosePort(6,3);
            leftPort.setLEDPin(10);
            leftPort.setRewardDuration(calib.left);
            leftPort.setToSingleRewardMode();
            global rightPort
            rightPort = NosePort(7,4);
            rightPort.setLEDPin(9);
            rightPort.setRewardDuration(calib.right);
            rightPort.setToSingleRewardMode();
            set(handles.getLeftCalibDuration,'enable','on');
            set(handles.getRightCalibDuration,'enable','on');
            set(handles.leftCalibDuration,'enable','on');
            set(handles.rightCalibDuration,'enable','on');
            set(handles.numIterations,'enable','on');
            global logFileID
            %fclose(logFileID);
            %fileToDel = dir('calibration_log*');
            %delete(fileToDel.name);
            set(handles.connectToArduino,'enable','off');
        end

        % Button pushed function: getLeftCalibDuration
        function getLeftCalibDuration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getLeftCalibDuration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % gets the length of the calibration reward duration for the left side as
            % inputted by the user and disperses it for the number of iterations the
            % user inputted, pausing 0.1 seconds between each reward
            
            global calib
            calib.left = str2double(get(handles.leftCalibDuration, 'String'));
            global leftPort
            leftPort.setRewardDuration(calib.left);
            iterations = str2double(get(handles.numIterations,'String'));
            for i = 1:iterations
                leftPort.deliverReward();
                pause(0.1);
            end
        end

        % Button pushed function: getRightCalibDuration
        function getRightCalibDuration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getRightCalibDuration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % gets the length of the calibration reward duration for the right side as
            % inputted by the user and disperses it for the number of iterations the
            % user inputted, pausing 0.1 seconds between each reward
            global calib
            calib.right = str2double(get(handles.rightCalibDuration, 'String'));
            global rightPort
            rightPort.setRewardDuration(calib.right);
            iterations = str2double(get(handles.numIterations,'String'));
            for i = 1:iterations
                rightPort.deliverReward();
                pause(0.1);
            end
        end

        % Button pushed function: reset
        function reset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %resets all handles to their intial state
            set(handles.centerPokeTrigger, 'enable', 'on');
            set(handles.centerPokeRewardWindow, 'enable', 'on');
            set(handles.leftRewardProb, 'enable', 'on');
            set(handles.rightRewardProb, 'enable', 'on');
            set(handles.rewardDurationRight, 'enable', 'on');
            set(handles.rewardDurationLeft, 'enable', 'on');
            set(handles.minInterTrialInterval, 'enable', 'on');
            set(handles.blockRangeMin, 'enable', 'on');
            set(handles.blockRangeMax, 'enable', 'on');
            set(handles.laserstimprob, 'enable', 'on');
            set(handles.markov, 'enable', 'off');
            set(handles.mouseName, 'enable', 'on');
            set(handles.folderPath, 'enable', 'on');
            set(handles.chooseFolder, 'enable', 'on');
            set(handles.runExperiment, 'enable', 'on');
            set(handles.stopExperiment,'enable','off');
            set(handles.save,'enable','off');
            set(handles.centerPokeTrigger, 'String','1');
            set(handles.centerPokeRewardWindow, 'String','2');
            set(handles.leftRewardProb, 'String','0.2');
            set(handles.rightRewardProb, 'String','0.8');
            set(handles.rewardDurationRight, 'String', '40');
            set(handles.rewardDurationLeft, 'String', '45');
            set(handles.minInterTrialInterval, 'String', '1');
            set(handles.blockRangeMin, 'String', '50');
            set(handles.blockRangeMax, 'String', '50');
            set(handles.laserstimprob, 'String', '0');
            set(handles.markov, 'String', '0');
            set(handles.useMarkov, 'Value', 0);
            set(handles.mouseName,'String','');
            set(handles.folderPath,'String','Default Folder Path');
            set(handles.save,'Value',1);
            set(handles.reset,'enable','on');
            set(handles.leftCalibDuration,'enable','on');
            set(handles.rightCalibDuration,'enable','on');
            set(handles.getLeftCalibDuration,'enable','on');
            set(handles.getRightCalibDuration,'enable','on');
            set(handles.leftCalibDuration,'String','45');
            set(handles.rightCalibDuration,'String','45');
            set(handles.numIterations,'String','100');
            set(handles.numIterations,'enable','off');
            set(handles.rightCalibDuration,'enable','off');
            set(handles.leftCalibDuration,'enable','off');
            set(handles.getLeftCalibDuration,'enable','off');
            set(handles.getRightCalibDuration,'enable','off');
            set(handles.connectToArduino,'enable','on');
            global calib
            calib.left = 45;
            calib.right = 45;
            set(handles.statsTable,'data',[0,0,0;0,0,0;0,0,0]);
            set(handles.statsTable,'enable','off');
        end

        % Button pushed function: runExperiment
        function runExperiment_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to runExperiment (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %Uses str2double(get(handles.PARAMETER_NAME, 'String')) to set each
            %property of the parameters struct, which has been instantiated as a global
            %variable
            %LEDDuringRewardWindow is automatically set to 1
            global p
            p = struct;
            p.centerPokeTrigger = str2double(get(handles.centerPokeTrigger, 'String'));
            p.centerPokeRewardWindow = str2double(get(handles.centerPokeRewardWindow, 'String'));
            p.ledDuringRewardWindow = 1;
            p.leftRewardProb = str2double(get(handles.leftRewardProb, 'String'));
            p.rightRewardProb = str2double(get(handles.rightRewardProb, 'String'));
            p.rewardDurationRight = str2double(get(handles.rewardDurationRight, 'String'));
            p.rewardDurationLeft = str2double(get(handles.rewardDurationLeft, 'String'));
            p.minInterTrialInterval = str2double(get(handles.minInterTrialInterval, 'String'));
            p.blockRangeMin = str2double(get(handles.blockRangeMin, 'String'));
            p.blockRangeMax = str2double(get(handles.blockRangeMax, 'String'));
            p.laserstimprob = str2double(get(handles.laserstimprob, 'String'));
            p.markov = str2double(get(handles.markov, 'String'));
            p.ismarkov = get(handles.useMarkov, 'Value');
            %creates a global info struct to store the mouse's name and the folder's
            %path as inputted by the user. sets the running field to true and the
            %save field to NaN
            global info
            info = struct;
            info.mouseName = lower(get(handles.mouseName, 'String'));
            info.folderName = get(handles.folderPath,'String');
            info.running = true;
            info.save = NaN;
            ready = true;
            
            %makes sure the user enters a mouse name and a directory
            if isempty(info.mouseName) || strcmp(info.folderName,'Default Folder Path') || strcmp(info.folderName,'0')
                ready = false;
                errordlg('Must input the mouse"s name and the directory in which the session"s data will be saved', 'INPUTS REQUIRED')
                %DIALOG BOX HERE
            end
            
            if p.ismarkov==0 && ~isempty(strfind(info.mouseName, 'cb')) % use checkbox first
                button = questdlg('Are you sure you don''t want to use Markovian transition probabilities?');
                if strcmp(button,'No') || strcmp(button, 'Cancel')
                    ready = false;
                end
            end
            if p.ismarkov==1
                if p.markov <= 0 || p.markov >= 1 % check if valid entry for tprob
                    ready = false;
                    errordlg('Please enter a value for Markovian Transition Probability between 0 and 1.')
                end
            end
            
            %if everything checks out, everything is disabled but the start and
            %save objects, and the experiment is run
            if ready
                set(handles.centerPokeTrigger, 'enable', 'off');
                set(handles.centerPokeRewardWindow, 'enable', 'off');
                set(handles.leftRewardProb, 'enable', 'off');
                set(handles.rightRewardProb, 'enable', 'off');
                set(handles.rewardDurationRight, 'enable', 'off');
                set(handles.rewardDurationLeft, 'enable', 'off');
                set(handles.minInterTrialInterval, 'enable', 'off');
                set(handles.blockRangeMin, 'enable', 'off');
                set(handles.blockRangeMax, 'enable', 'off');
                set(handles.laserstimprob, 'enable', 'off');
                set(handles.markov, 'enable', 'off');
                set(handles.mouseName, 'enable', 'off');
                set(handles.folderPath, 'enable', 'off');
                set(handles.chooseFolder, 'enable', 'off');
                set(handles.runExperiment,'enable','off');
                set(handles.stopExperiment,'enable','on');
                set(handles.save,'enable','on');
                set(handles.reset,'enable','off');
                set(handles.connectToArduino,'enable','off');
                set(handles.leftCalibDuration,'enable','off');
                set(handles.rightCalibDuration,'enable','off');
                set(handles.getLeftCalibDuration,'enable','off');
                set(handles.getRightCalibDuration,'enable','off');
                set(handles.statsTable,'enable','on');
                if p.ismarkov == 0
                    runTriplePortExperiment_laser
                elseif p.ismarkov ==1
                    runTriplePortExperiment_markov
                else
                    errordlg('Please enter a value for Markovian Transition Probability between 0 and 1.')
                end
            
            end
        end

        % Button pushed function: stopExperiment
        function stopExperiment_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stopExperiment (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %sets the running field of info to false and determines whether or not the
            %user wanted to save the data. also sets up an input dialog box to make
            %sure the user wants to discard data if they uncheck the box
            global info
            info.save = get(handles.save,'Value');
            if ~info.save
                answer = lower(inputdlg('Do you want to save data for this session?','SAVE?'));
                if ~strcmp(answer,'n') && ~strcmp(answer,'no')
                    set(handles.save,'Value',1);
                    info.save = 1;
                end
            end
            info.running = false;
            
            % 2021-04-16 OM: Do not reset the parameters every time you
            % stop a session.
            % reset_Callback(hObject, eventdata, handles)
        end

        % Value changed function: useMarkov
        function useMarkov_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to useMarkov (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.useMarkov.Value == 1
                set(handles.markov, 'enable', 'on');
                set(handles.blockRangeMin, 'enable', 'off');
                set(handles.blockRangeMax, 'enable', 'off');
                set(handles.laserstimprob, 'enable', 'off');
            else
                set(handles.markov, 'enable', 'off');
                set(handles.blockRangeMin, 'enable', 'on');
                set(handles.blockRangeMax, 'enable', 'on');
                set(handles.laserstimprob, 'enable', 'on');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'NewGUI';
            app.figure1.Tag = 'figure1';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Calibration';
            app.connectToArduino = uibutton(app.figure1, 'push');
            app.connectToArduino.ButtonPushedFcn = createCallbackFcn(app, @connectToArduino_Callback, true);
            app.connectToArduino.Tag = 'connectToArduino';
            app.connectToArduino.Text = 'Connect to Arduino';
            app.numIterations = uieditfield(app.figure1, 'text');
            app.numIterations.Tag = 'numIterations';
            app.numIterations.Value = '100';
            app.text32 = uilabel(app.figure1);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Number of Iterations:';
            app.text33 = uilabel(app.figure1);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Left Duration (ms)';
            app.text34 = uilabel(app.figure1);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Right Duration (ms)';
            app.leftCalibDuration = uieditfield(app.figure1, 'text');
            app.leftCalibDuration.Tag = 'leftCalibDuration';
            app.leftCalibDuration.Value = '45';
            app.rightCalibDuration = uieditfield(app.figure1, 'text');
            app.rightCalibDuration.Tag = 'rightCalibDuration';
            app.rightCalibDuration.Value = '45';
            app.getRightCalibDuration = uibutton(app.figure1, 'push');
            app.getRightCalibDuration.ButtonPushedFcn = createCallbackFcn(app, @getRightCalibDuration_Callback, true);
            app.getRightCalibDuration.Tag = 'getRightCalibDuration';
            app.getRightCalibDuration.Text = 'Flush Right';
            app.centerPokeTrigger = uieditfield(app.figure1, 'text');
            app.centerPokeTrigger.Tag = 'centerPokeTrigger';
            app.centerPokeTrigger.Value = '1';
            app.centerPokeRewardWindow = uieditfield(app.figure1, 'text');
            app.centerPokeRewardWindow.Tag = 'centerPokeRewardWindow';
            app.centerPokeRewardWindow.Value = '2';
            app.leftRewardProb = uieditfield(app.figure1, 'text');
            app.leftRewardProb.Tag = 'leftRewardProb';
            app.leftRewardProb.Value = '0.2';
            app.rightRewardProb = uieditfield(app.figure1, 'text');
            app.rightRewardProb.Tag = 'rightRewardProb';
            app.rightRewardProb.Value = '0.8';
            app.rewardDurationRight = uieditfield(app.figure1, 'text');
            app.rewardDurationRight.Tag = 'rewardDurationRight';
            app.rewardDurationRight.Value = '40';
            app.rewardDurationLeft = uieditfield(app.figure1, 'text');
            app.rewardDurationLeft.Tag = 'rewardDurationLeft';
            app.rewardDurationLeft.Value = '45';
            app.minInterTrialInterval = uieditfield(app.figure1, 'text');
            app.minInterTrialInterval.Tag = 'minInterTrialInterval';
            app.minInterTrialInterval.Value = '1';
            app.blockRangeMin = uieditfield(app.figure1, 'text');
            app.blockRangeMin.Tag = 'blockRangeMin';
            app.blockRangeMin.Value = '50';
            app.blockRangeMax = uieditfield(app.figure1, 'text');
            app.blockRangeMax.Tag = 'blockRangeMax';
            app.blockRangeMax.Value = '50';
            app.text12 = uilabel(app.figure1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Parameters';
            app.statsTable = uitable(app.figure1);
            app.statsTable.ColumnName = {'Left'; 'Right'; 'Total'};
            app.statsTable.RowName = {'Rewards'; 'Trials'; 'Blocks'};
            app.statsTable.ColumnEditable = [false false false];
            app.statsTable.Tag = 'statsTable';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Maximum Block Range:';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Minimum Block Range:';
            app.text25 = uilabel(app.figure1);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Minimum Intertrial Interval (sec):';
            app.text26 = uilabel(app.figure1);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Reward Duration Left (ms):';
            app.text27 = uilabel(app.figure1);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Reward Duration Right (ms):';
            app.text28 = uilabel(app.figure1);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Right Reward Probability:';
            app.text29 = uilabel(app.figure1);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Left Reward Probability';
            app.text30 = uilabel(app.figure1);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Center Poke Reward Window (sec):';
            app.text31 = uilabel(app.figure1);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Center Poke Trigger';
            app.mouseName = uieditfield(app.figure1, 'text');
            app.mouseName.Tag = 'mouseName';
            app.chooseFolder = uibutton(app.figure1, 'push');
            app.chooseFolder.ButtonPushedFcn = createCallbackFcn(app, @chooseFolder_Callback, true);
            app.chooseFolder.Tag = 'chooseFolder';
            app.chooseFolder.Text = 'Choose Directory to Save in';
            app.folderPath = uilabel(app.figure1);
            app.folderPath.Tag = 'folderPath';
            app.folderPath.Text = 'Default Folder Path';
            app.runExperiment = uibutton(app.figure1, 'push');
            app.runExperiment.ButtonPushedFcn = createCallbackFcn(app, @runExperiment_Callback, true);
            app.runExperiment.Tag = 'runExperiment';
            app.runExperiment.Text = 'Run Experiment';
            app.stopExperiment = uibutton(app.figure1, 'push');
            app.stopExperiment.ButtonPushedFcn = createCallbackFcn(app, @stopExperiment_Callback, true);
            app.stopExperiment.Tag = 'stopExperiment';
            app.stopExperiment.Text = 'Stop Experiment';
            app.save = uicheckbox(app.figure1);
            app.save.Tag = 'save';
            app.save.Text = 'Save Parameters, Stats, and Poke History';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Mouse Name:';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Experiment Settings';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Stats';
            app.reset = uibutton(app.figure1, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @reset_Callback, true);
            app.reset.Tag = 'reset';
            app.reset.Text = 'Reset';
            app.getLeftCalibDuration = uibutton(app.figure1, 'push');
            app.getLeftCalibDuration.ButtonPushedFcn = createCallbackFcn(app, @getLeftCalibDuration_Callback, true);
            app.getLeftCalibDuration.Tag = 'getLeftCalibDuration';
            app.getLeftCalibDuration.Text = 'Flush Left';
            app.laserstimprob = uieditfield(app.figure1, 'text');
            app.laserstimprob.Tag = 'laserstimprob';
            app.laserstimprob.Value = '0';
            app.markov = uieditfield(app.figure1, 'text');
            app.markov.Tag = 'markov';
            app.markov.Enable = 'off';
            app.markov.Value = '0';
            app.text38 = uilabel(app.figure1);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Laser Stim Probability:';
            app.useMarkov = uicheckbox(app.figure1);
            app.useMarkov.ValueChangedFcn = createCallbackFcn(app, @useMarkov_Callback, true);
            app.useMarkov.Tag = 'useMarkov';
            app.useMarkov.Text = 'Markovian Transition Prob?';
            app.text40 = uilabel(app.figure1);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Value:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)NewGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end